classdef CeuL10_DirectionalDerivativeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ComputeDirectionalDerivativeLabel  matlab.ui.control.Label
        ChoosemethodButtonGroup  matlab.ui.container.ButtonGroup
        SobelButton              matlab.ui.control.RadioButton
        PrewittButton            matlab.ui.control.RadioButton
        CentralButton            matlab.ui.control.RadioButton
        IntermediateButton       matlab.ui.control.RadioButton
        ExecuteButton            matlab.ui.control.Button
        ImageLabel               matlab.ui.control.Label
    end

    % Author: Carlos Oscar Sorzano
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            %I = imread('fluorescenceCells.jpg')
            gI = rgb2gray(I);
            
            
            if app.SobelButton.Value
                [Gx, Gy]=imgradientxy(gI, 'sobel');
                figure
                imshowpair(Gx, Gy, 'montage');
                title('Directional Gradients: x-direction, Gx (left) and y-direction, Gy (right), using Sobel method');
                
            end
            
            if app.PrewittButton.Value
                [Gx, Gy]=imgradientxy(gI, 'prewitt');
                figure
                imshowpair(Gx, Gy, 'montage');
                title('Directional Gradients: x-direction, Gx (left) and y-direction, Gy (right), using Prewitt method');
                
            end
            
             if app.CentralButton.Value
                [Gx, Gy]=imgradientxy(gI, 'central');
                figure
                imshowpair(Gx, Gy, 'montage');
                title('Directional Gradients: x-direction, Gx (left) and y-direction, Gy (right), using Central method');
                
             end
             
             if app.IntermediateButton.Value
                [Gx, Gy]=imgradientxy(gI, 'intermediate');
                figure
                imshowpair(Gx, Gy, 'montage');
                title('Directional Gradients: x-direction, Gx (left) and y-direction, Gy (right), using Intermediate method');
                
            end
            
            
            app.parentApp.updateImageList();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ComputeDirectionalDerivativeLabel = uilabel(app.UIFigure);
            app.ComputeDirectionalDerivativeLabel.Text = 'Compute Directional Derivative';
            app.ChoosemethodButtonGroup = uibuttongroup(app.UIFigure);
            app.ChoosemethodButtonGroup.Title = 'Choose method';
            app.SobelButton = uiradiobutton(app.ChoosemethodButtonGroup);
            app.SobelButton.Text = 'Sobel';
            app.SobelButton.Value = true;
            app.PrewittButton = uiradiobutton(app.ChoosemethodButtonGroup);
            app.PrewittButton.Text = 'Prewitt';
            app.CentralButton = uiradiobutton(app.ChoosemethodButtonGroup);
            app.CentralButton.Text = 'Central';
            app.IntermediateButton = uiradiobutton(app.ChoosemethodButtonGroup);
            app.IntermediateButton.Text = 'Intermediate';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL10_DirectionalDerivativeApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end