classdef CeuL11_PixelizationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ImagePixelizationLabel  matlab.ui.control.Label
        ViewpixelregionsButton  matlab.ui.control.Button
        ImageLabel              matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        SliderLabel             matlab.ui.control.Label
        Slider                  matlab.ui.control.Slider
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            app.Image.ImageSource=app.h.Name;
        end

        % Button pushed function: ViewpixelregionsButton
        function ViewpixelregionsButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            IExp=app.Image.ImageSource;
            imshow(IExp);
            impixelregion;
%bermejo4
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = round(app.Slider.Value);
            
            I = getimage(app.h);
            windowName = app.h.Name;

            fun1 = @(block_struct) round(mean(block_struct.data(:)),1) * ones(size(block_struct.data));

            I2=zeros(size(I));
            
            I2(:,:,1) = blockproc(I(:,:,1),[value value],fun1);
            I2(:,:,2) = blockproc(I(:,:,2),[value value],fun1);
            I2(:,:,3) = blockproc(I(:,:,3),[value value],fun1);
            
            I3=cast(I2, 'uint8');
            app.Image.ImageSource=I3;
%bermejo4
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImagePixelizationLabel = uilabel(app.UIFigure);
            app.ImagePixelizationLabel.Text = 'Image Pixelization:';
            app.ViewpixelregionsButton = uibutton(app.UIFigure, 'push');
            app.ViewpixelregionsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewpixelregionsButtonPushed, true);
            app.ViewpixelregionsButton.Text = 'View pixel regions';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.Image = uiimage(app.UIFigure);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 128];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL11_PixelizationApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end