classdef CeuL17_MedianFilterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MedianFilterLabel             matlab.ui.control.Label
        ImageLabel                    matlab.ui.control.Label
        NeightborhoodDimensionSliderLabel  matlab.ui.control.Label
        NeightborhoodDimensionSlider  matlab.ui.control.Slider
        ShowImage                     matlab.ui.control.UIAxes
        ExportimageButton             matlab.ui.control.Button
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
        medfilimg % Description
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
    
            I = getimage(app.h);
            image(app.ShowImage,I);
            axis(app.ShowImage,'off');
            axis square
        end

        % Value changed function: NeightborhoodDimensionSlider
        function NeightborhoodDimensionSliderValueChanged(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            if length(size(I))==3
                R = I(:,:,1);
                G = I(:,:,2);
                B = I(:,:,3);

                SE = round(app.NeightborhoodDimensionSlider.Value);
                app.medfilimg=zeros(size(I));
                app.medfilimg(:,:,1)=medfilt2(R,[SE SE]);
                app.medfilimg(:,:,2)=medfilt2(G,[SE SE]);
                app.medfilimg(:,:,3)=medfilt2(B,[SE SE]);
               
                image(app.ShowImage,uint8(app.medfilimg));
            end
        end

        % Button pushed function: ExportimageButton
        function ExportimageButtonPushed(app, event)
            figure;
            image(uint8(app.medfilimg));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MedianFilterLabel = uilabel(app.UIFigure);
            app.MedianFilterLabel.Text = 'Median Filter';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.NeightborhoodDimensionSliderLabel = uilabel(app.UIFigure);
            app.NeightborhoodDimensionSliderLabel.Text = 'Neightborhood Dimension';
            app.NeightborhoodDimensionSlider = uislider(app.UIFigure);
            app.NeightborhoodDimensionSlider.Limits = [3 13];
            app.NeightborhoodDimensionSlider.ValueChangedFcn = createCallbackFcn(app, @NeightborhoodDimensionSliderValueChanged, true);
            app.NeightborhoodDimensionSlider.Value = 3;
            app.ShowImage = uiaxes(app.UIFigure);
            title(app.ShowImage, 'Median Filter')
            xlabel(app.ShowImage, 'X')
            ylabel(app.ShowImage, 'Y')
            app.ExportimageButton = uibutton(app.UIFigure, 'push');
            app.ExportimageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportimageButtonPushed, true);
            app.ExportimageButton.Text = 'Export image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL17_MedianFilterApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end