classdef CeuL19_bothat_tophatApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BotHatandTopHatLabel          matlab.ui.control.Label
        ChooseactionButtonGroup       matlab.ui.container.ButtonGroup
        TopHatButton                  matlab.ui.control.RadioButton
        BotHatButton                  matlab.ui.control.RadioButton
        ExecuteButton                 matlab.ui.control.Button
        ImageLabel                    matlab.ui.control.Label
        DimensionofshapeLabel         matlab.ui.control.Label
        NeightborhoodDimensionSlider  matlab.ui.control.Slider
        TypesofshapeButtonGroup       matlab.ui.container.ButtonGroup
        diskButton                    matlab.ui.control.RadioButton
        diamondButton                 matlab.ui.control.RadioButton
        squareButton                  matlab.ui.control.RadioButton
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            se = round(app.NeightborhoodDimensionSlider.Value);
            
            if app.diskButton.Value
                SE = strel('disk',se);
            elseif app.diamondButton.Value
                SE = strel('diamond',se);
            else 
                SE = strel('square',se);
            end
                
            
            if app.TopHatButton.Value
                J=imtophat(I,SE);
            elseif app.BotHatButton.Value
                J=imbothat(I,SE);
            end 
                 
            figure
            imshow(J);
            app.parentApp.updateImageList();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BotHatandTopHatLabel = uilabel(app.UIFigure);
            app.BotHatandTopHatLabel.Text = 'BotHat and TopHat ';
            app.ChooseactionButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseactionButtonGroup.Title = 'Choose action';
            app.TopHatButton = uiradiobutton(app.ChooseactionButtonGroup);
            app.TopHatButton.Text = 'TopHat';
            app.TopHatButton.Value = true;
            app.BotHatButton = uiradiobutton(app.ChooseactionButtonGroup);
            app.BotHatButton.Text = 'BotHat';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.DimensionofshapeLabel = uilabel(app.UIFigure);
            app.DimensionofshapeLabel.Text = 'Dimension of shape';
            app.NeightborhoodDimensionSlider = uislider(app.UIFigure);
            app.NeightborhoodDimensionSlider.Limits = [3 13];
            app.NeightborhoodDimensionSlider.Value = 3;
            app.TypesofshapeButtonGroup = uibuttongroup(app.UIFigure);
            app.TypesofshapeButtonGroup.Title = 'Types of shape';
            app.diskButton = uiradiobutton(app.TypesofshapeButtonGroup);
            app.diskButton.Text = 'disk';
            app.diskButton.Value = true;
            app.diamondButton = uiradiobutton(app.TypesofshapeButtonGroup);
            app.diamondButton.Text = 'diamond';
            app.squareButton = uiradiobutton(app.TypesofshapeButtonGroup);
            app.squareButton.Text = 'square';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL19_bothat_tophatApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end