classdef CeuL2_MergeRGBChannelsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SeparateRGBchannelsLabel  matlab.ui.control.Label
        ExecuteButton             matlab.ui.control.Button
        RedDropDownLabel          matlab.ui.control.Label
        RedDropDown               matlab.ui.control.DropDown
        GreenDropDownLabel        matlab.ui.control.Label
        GreenDropDown             matlab.ui.control.DropDown
        BlueDropDownLabel         matlab.ui.control.Label
        BlueDropDown              matlab.ui.control.DropDown
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;

            figHandles = findobj('Type', 'figure');
            if length(figHandles)>0
                app.RedDropDown.Items = {};
                app.GreenDropDown.Items = {};
                app.BlueDropDown.Items = {};
                for i=1:length(figHandles)
                    app.RedDropDown.Items{i} = figHandles(i).Name;
                    app.GreenDropDown.Items{i} = figHandles(i).Name;
                    app.BlueDropDown.Items{i} = figHandles(i).Name;
                end
            end    
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            hr = app.parentApp.getFigureHandlerByName(app.RedDropDown.Value);
            hg = app.parentApp.getFigureHandlerByName(app.GreenDropDown.Value);
            hb = app.parentApp.getFigureHandlerByName(app.BlueDropDown.Value);
            
            R = getimage(hr);
            G = getimage(hg);
            B = getimage(hb);
            I=zeros([size(R,1) size(R,2) 3]);
            I(:,:,1) = R;
            I(:,:,2) = G;
            I(:,:,3) = B;
            
            figure('Name','Merged channels');
            imshow(uint8(I));
            app.parentApp.updateImageList();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SeparateRGBchannelsLabel = uilabel(app.UIFigure);
            app.SeparateRGBchannelsLabel.Text = 'Separate RGB channels';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.RedDropDownLabel = uilabel(app.UIFigure);
            app.RedDropDownLabel.Text = 'Red';
            app.RedDropDown = uidropdown(app.UIFigure);
            app.GreenDropDownLabel = uilabel(app.UIFigure);
            app.GreenDropDownLabel.Text = 'Green';
            app.GreenDropDown = uidropdown(app.UIFigure);
            app.BlueDropDownLabel = uilabel(app.UIFigure);
            app.BlueDropDownLabel.Text = 'Blue';
            app.BlueDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL2_MergeRGBChannelsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end