classdef CeuL2_ShowColormapApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ShowColormapLabel  matlab.ui.control.Label
        ImageLabel         matlab.ui.control.Label
        ColormapTable      matlab.ui.control.Table
        imageAxis          matlab.ui.control.UIAxes
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
        I         % image used 
        map       % colormap of the image
        imag      % imageDisplayed NOT USED
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            
            app.map = app.h.CurrentAxes.Colormap;
            app.map=[(1:size(app.map,1))' app.map]; 
            set(app.ColormapTable,'Data',app.map);
            
            
            %we establish the image and the colormap
            app.I = getimage(app.h);
            image(app.imageAxis, app.I);
            colormap(app.imageAxis, app.map(:,2:4));
            axis(app.imageAxis,'off');
            
            
        end

        % Cell selection callback: ColormapTable
        function ColormapTableCellSelection(app, event)
            app.I = getimage(app.h);
            
            indices = event.Indices;
            map2 = app.map;
            map2(indices(1),2:4)=[1 1 1]; %el map of size 256 its going to be initialized to one 
            
            I2 = app.I; %new image with the values changed is goin to be initialized as the original one 
            I2(I2==indices(1))=256; %the new image is generated (with the points changed to white)

            image(app.imageAxis, I2); %show the new image 
            colormap(app.imageAxis, map2(:,2:4)); %change the colormap of the image to white  

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ShowColormapLabel = uilabel(app.UIFigure);
            app.ShowColormapLabel.Text = {'Show Colormap'; ''};
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.ColormapTable = uitable(app.UIFigure);
            app.ColormapTable.ColumnName = {'Number'; 'Red channel'; 'Green channel'; 'Blue channel'};
            app.ColormapTable.RowName = {};
            app.ColormapTable.CellSelectionCallback = createCallbackFcn(app, @ColormapTableCellSelection, true);
            app.imageAxis = uiaxes(app.UIFigure);
            title(app.imageAxis, '')
            xlabel(app.imageAxis, '')
            ylabel(app.imageAxis, '')
            app.imageAxis.XTick = [];
            app.imageAxis.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL2_ShowColormapApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end