classdef CeuL2_SplitRGBChannelsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SeparateRGBchannelsLabel  matlab.ui.control.Label
        ChooseactionButtonGroup   matlab.ui.container.ButtonGroup
        ColormapsButton           matlab.ui.control.RadioButton
        GraymapsButton            matlab.ui.control.RadioButton
        ExecuteButton             matlab.ui.control.Button
        ImageLabel                matlab.ui.control.Label
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            if length(size(I))==3
                R = I(:,:,1);
                G = I(:,:,2);
                B = I(:,:,3);
                hr = figure('Name',strcat(windowName," (red)"));
                imshow(R);
                hg = figure('Name',strcat(windowName," (green)"));
                imshow(G);
                hb = figure('Name',strcat(windowName," (blue)"));
                imshow(B);
                if app.ColormapsButton.Value
                    z = zeros(256,1);
                    l = ([0:255]')/255;
                    figure(hr);
                    colormap([l z z]);
                    figure(hg);
                    colormap([z l z]);
                    figure(hb);
                    colormap([z z l]);                    
                end
                app.parentApp.updateImageList();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SeparateRGBchannelsLabel = uilabel(app.UIFigure);
            app.SeparateRGBchannelsLabel.Text = 'Separate RGB channels';
            app.ChooseactionButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseactionButtonGroup.Title = 'Choose action';
            app.ColormapsButton = uiradiobutton(app.ChooseactionButtonGroup);
            app.ColormapsButton.Text = 'Color maps';
            app.ColormapsButton.Value = true;
            app.GraymapsButton = uiradiobutton(app.ChooseactionButtonGroup);
            app.GraymapsButton.Text = 'Gray maps';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL2_SplitRGBChannelsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end