classdef CeuL3_2ValuesMappingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        Image                matlab.ui.control.Image
        ImageLabel           matlab.ui.control.Label
        WarningLabel         matlab.ui.control.Label
        ExportImageButton    matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
        DescriptionLabel     matlab.ui.control.Label
        Histogram            matlab.ui.control.UIAxes
        MinValueSliderLabel  matlab.ui.control.Label
        MinValueSlider       matlab.ui.control.Slider
        MaxvalueSliderLabel  matlab.ui.control.Label
        MaxValueSlider       matlab.ui.control.Slider
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Author: Aïcha Díez de Benito
    
    properties (Access = private)
        parentApp
        min_value
        max_value
        J % binarized image
        h   %figure handler
        I
        V

    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;  % Image handler
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            app.Image.ImageSource=app.h.Name;
            app.UIFigure.Name = app.h.Name;
            app.MaxValueSlider.Value = 255;
            app.min_value = uint8(app.MinValueSlider.Value);
            app.max_value = uint8(app.MaxValueSlider.Value); 
            
            app.I = getimage(app.h);
            app.V = uint8(rgb2gray(app.I));
            [counts, bins] = imhist(app.V);
            distCounts = counts / sum(counts);
            plot(app.Histogram, bins, distCounts);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {486, 486};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {373, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: MinValueSlider
        function MinValueSliderValueChanged(app, event)
            app.WarningLabel.Visible = false;
            app.min_value = app.MinValueSlider.Value;
            app.MaxValueSlider.Enable = true;
            
            
            if app.min_value <= app.max_value
                
%                 V = uint8(sum(I,3) / 3); % the statement returns a monochromatic image of 8-bit integers
                
                
                BW_low = imbinarize(app.V, app.min_value/255); % Convert the image into a binary image using the threshold.
                BW_high = imbinarize(app.V, app.max_value/255);
                app.J = imfuse(BW_low, BW_high, 'diff');

                app.Image.ImageSource = repmat(app.J,1,1,3);
                
            else
                app.WarningLabel.Visible = true;
                app.MaxvalueSliderLabel.Visible = true;
                app.MinValueSlider.Value = 0;
                app.MaxValueSlider.Value = 255;
                app.min_value = app.MinValueSlider.Value;
                app.max_value = app.MaxValueSlider.Value; 
            end
            
            app.parentApp.updateImageList();
        end

        % Value changed function: MaxValueSlider
        function MaxValueSliderValueChanged(app, event)
            app.WarningLabel.Visible = false;
            app.max_value = app.MaxValueSlider.Value;
            app.MaxValueSlider.Enable = true;
            
            if app.min_value <= app.max_value
                
                BW_low = imbinarize(app.V, app.min_value/255); % Convert the image into a binary image using the threshold.
                BW_high = imbinarize(app.V, app.max_value/255);
                app.J = imfuse(BW_low, BW_high, 'diff');
                
                app.Image.ImageSource = repmat(app.J,1,1,3);
                
                
            else
                app.WarningLabel.Visible = true;
                app.MaxvalueSliderLabel.Visible = true;
                app.MinValueSlider.Value = 0;
                app.MaxValueSlider.Value = 255;
                app.min_value = app.MinValueSlider.Value;
                app.max_value = app.MaxValueSlider.Value;  
                
            end
            app.parentApp.updateImageList();
        end

        % Button pushed function: ExportImageButton
        function ExportImageButtonPushed(app, event)
            windowName = app.h.Name;
            
            if app.min_value > app.max_value
                app.WarningLabel.Visible = true;
                app.MaxvalueSliderLabel.Visible = true;
                app.MinValueSlider.Value = 0;
                app.MaxValueSlider.Value = 255;
                app.min_value = app.MinValueSlider.Value;
                app.max_value = app.MaxValueSlider.Value;  
                
                app.Image.ImageSource = repmat(app.J,1,1,3);
            else
                fig = figure('Name',strcat(windowName," (Pixel interval matching)"));
                figure(fig);
                imshow(app.J);
            end
                
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UIFigure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.ImageLabel = uilabel(app.LeftPanel);
            app.ImageLabel.Text = 'Image:';
            app.WarningLabel = uilabel(app.LeftPanel);
            app.WarningLabel.Visible = 'off';
            app.WarningLabel.Text = 'WARNING! Min value has to be higher than Max value.';
            app.ExportImageButton = uibutton(app.LeftPanel, 'push');
            app.ExportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportImageButtonPushed, true);
            app.ExportImageButton.Text = 'Export to other window';
            app.RightPanel = uipanel(app.GridLayout);
            app.DescriptionLabel = uilabel(app.RightPanel);
            app.DescriptionLabel.Text = 'Select a range of values to be shown ';
            app.Histogram = uiaxes(app.RightPanel);
            title(app.Histogram, 'Grey image Histogram')
            xlabel(app.Histogram, 'Pixel value')
            ylabel(app.Histogram, 'Density')
            app.Histogram.XLim = [0 255];
            app.Histogram.XTick = [-10 0 50 100 150 200 255];
            app.Histogram.XTickLabel = {'-10'; '0'; '50'; '100'; '150'; '200'; '255'};
            app.Histogram.YTickLabel = '';
            app.MinValueSliderLabel = uilabel(app.RightPanel);
            app.MinValueSliderLabel.Text = 'Min Value';
            app.MinValueSlider = uislider(app.RightPanel);
            app.MinValueSlider.Limits = [0 255];
            app.MinValueSlider.ValueChangedFcn = createCallbackFcn(app, @MinValueSliderValueChanged, true);
            app.MaxvalueSliderLabel = uilabel(app.RightPanel);
            app.MaxvalueSliderLabel.Enable = 'off';
            app.MaxvalueSliderLabel.Text = 'Max value';
            app.MaxValueSlider = uislider(app.RightPanel);
            app.MaxValueSlider.Limits = [0 255];
            app.MaxValueSlider.ValueChangedFcn = createCallbackFcn(app, @MaxValueSliderValueChanged, true);
            app.MaxValueSlider.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL3_2ValuesMappingApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end