classdef CeuL3_ComputeIntensityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ComputeintensityLabel     matlab.ui.control.Label
        ExecuteButton             matlab.ui.control.Button
        ImageLabel                matlab.ui.control.Label
        IntensityDropDownLabel    matlab.ui.control.Label
        IntensityDropDown         matlab.ui.control.DropDown
        RedweightwRSliderLabel    matlab.ui.control.Label
        RedweightwRSlider         matlab.ui.control.Slider
        GreenweightwGSliderLabel  matlab.ui.control.Label
        GreenweightwGSlider       matlab.ui.control.Slider
        BlueweightwBSliderLabel   matlab.ui.control.Label
        BlueweightwBSlider        matlab.ui.control.Slider
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            
            app.IntensityDropDownValueChanged(0);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            if length(size(I))==3
                R = double(I(:,:,1));
                G = double(I(:,:,2));
                B = double(I(:,:,3));
                
                idx = find(strcmp(app.IntensityDropDown.Items, ...
                                  app.IntensityDropDown.Value));
                
                if idx==1
                    I = (R+G+B)/3;
                    windowName = strcat(windowName," (value)");
                elseif idx==2
                    I = 0.299*R+0.587*G+0.114*B;
                    windowName = strcat(windowName," (luminance)");
                elseif idx==3
                    wR = app.RedweightwRSlider.Value;
                    wG = app.GreenweightwGSlider.Value;
                    wB = app.BlueweightwBSlider.Value;
                    w = wR+wG+wB;
                    if w==0
                        wR=1;
                        wG=1;
                        wB=1;
                        w=wR+wG+wB;
                    end
                    I = wR/w*R+wG/w*G+wB/w*B;
                    weightStr = sprintf(" (custom intensity wR=%f wG=%f wB=%f)",wR,wG,wB);
                    windowName = strcat(windowName,weightStr);
                end
                
                figure('Name',windowName);
                image(I);
                axis off;
                colormap gray;
                colorbar;
                
                app.parentApp.updateImageList();
            end
        end

        % Value changed function: IntensityDropDown
        function IntensityDropDownValueChanged(app, event)
            idx = find(strcmp(app.IntensityDropDown.Items, ...
                                  app.IntensityDropDown.Value));
            if idx==1 || idx==2
                app.RedweightwRSlider.Visible=false;
                app.GreenweightwGSlider.Visible=false;
                app.BlueweightwBSlider.Visible=false;
                
                app.RedweightwRSliderLabel.Visible=false;
                app.GreenweightwGSliderLabel.Visible=false;
                app.BlueweightwBSliderLabel.Visible=false;
            else
                app.RedweightwRSlider.Visible=true;
                app.GreenweightwGSlider.Visible=true;
                app.BlueweightwBSlider.Visible=true;

                app.RedweightwRSliderLabel.Visible=true;
                app.GreenweightwGSliderLabel.Visible=true;
                app.BlueweightwBSliderLabel.Visible=true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ComputeintensityLabel = uilabel(app.UIFigure);
            app.ComputeintensityLabel.Text = 'Compute intensity';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.IntensityDropDownLabel = uilabel(app.UIFigure);
            app.IntensityDropDownLabel.Text = 'Intensity';
            app.IntensityDropDown = uidropdown(app.UIFigure);
            app.IntensityDropDown.Items = {'Value (1/3, 1/3, 1/3)', 'Luminance (0.299, 0.587, 0.114)', 'Custom (wR, wG, wB)'};
            app.IntensityDropDown.ValueChangedFcn = createCallbackFcn(app, @IntensityDropDownValueChanged, true);
            app.IntensityDropDown.Value = 'Value (1/3, 1/3, 1/3)';
            app.RedweightwRSliderLabel = uilabel(app.UIFigure);
            app.RedweightwRSliderLabel.Text = 'Red weight (wR)';
            app.RedweightwRSlider = uislider(app.UIFigure);
            app.RedweightwRSlider.Limits = [0 1];
            app.RedweightwRSlider.Value = 1;
            app.GreenweightwGSliderLabel = uilabel(app.UIFigure);
            app.GreenweightwGSliderLabel.Text = 'Green weight (wG)';
            app.GreenweightwGSlider = uislider(app.UIFigure);
            app.GreenweightwGSlider.Limits = [0 1];
            app.GreenweightwGSlider.Value = 1;
            app.BlueweightwBSliderLabel = uilabel(app.UIFigure);
            app.BlueweightwBSliderLabel.Text = 'Blue weight (wB)';
            app.BlueweightwBSlider = uislider(app.UIFigure);
            app.BlueweightwBSlider.Limits = [0 1];
            app.BlueweightwBSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL3_ComputeIntensityApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end