classdef CeuL3_ComputePdfAndCdfApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ComputeCDFLabel  matlab.ui.control.Label
        ImageLabel       matlab.ui.control.Label
        CheckBoxRed      matlab.ui.control.CheckBox
        CheckBoxGreen    matlab.ui.control.CheckBox
        CheckBoxBlue     matlab.ui.control.CheckBox
        CDFPlot          matlab.ui.control.UIAxes
        HistogramPlot    matlab.ui.control.UIAxes
    end

    % Author: Gonzalo Calvo    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
        bins
        pdfR
        cdfR
        pdfG
        cdfG
        pdfB
        cdfB
        tick = 0.05;
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            I = getimage(app.h);
            if length(size(I))==3
                %we need 3 plots, one for each color.
                
                R = I(:,:,1);
                G = I(:,:,2);
                B = I(:,:,3);
                
                [countsR, binsR] = imhist( R );
                [countsG, binsG] = imhist( G );
                [countsB, binsB] = imhist( B );
                
                app.pdfR = countsR/sum(countsR);
                app.pdfG = countsG/sum(countsG);
                app.pdfB = countsB/sum(countsB);
                
                maxR = max(max(app.pdfR));
                maxG = max(max(app.pdfG));
                maxB = max(max(app.pdfB));
                
                maximumValue = max([ maxR maxG maxB ]);
                
                app.HistogramPlot.YScale = 'linear';
                app.HistogramPlot.YTick = 0: app.tick : maximumValue + app.tick;
                app.HistogramPlot.YTickLabelMode = 'auto';
                
                plot (app.HistogramPlot, binsR, app.pdfR, "r" );
                plot (app.HistogramPlot, binsG, app.pdfG, "g" );
                plot (app.HistogramPlot, binsB, app.pdfB, "b" );
                
                app.cdfR = cumsum(countsR) / sum(countsR);
                app.cdfG = cumsum(countsG) / sum(countsG);
                app.cdfB = cumsum(countsB) / sum(countsB);
                    
                plot (app.CDFPlot, binsR, app.cdfR, "r" );
                plot (app.CDFPlot, binsG, app.cdfG, "g" );
                plot (app.CDFPlot, binsB, app.cdfB, "b");
                
                app.bins = binsR;
                
                linkaxes([app.HistogramPlot app.CDFPlot], 'x');
            end
        end

        % Callback function
        function IntensityDropDownValueChanged(app, event)
            
        end

        % Value changed function: CheckBoxBlue, CheckBoxGreen, 
        % CheckBoxRed
        function recreatePlots(app, event)
            valueR = app.CheckBoxRed.Value;
            valueG = app.CheckBoxGreen.Value;
            valueB = app.CheckBoxBlue.Value;
            
            cla(app.CDFPlot);
            cla(app.HistogramPlot);            
            
            maxR = 0;
            if valueR == 1
                plot (app.HistogramPlot, app.bins, app.pdfR, "r" );
                plot (app.CDFPlot, app.bins, app.cdfR, "r" );
                maxR = max(max(app.pdfR));
            end
            
            maxG = 0;
            if valueG == 1
                plot (app.HistogramPlot, app.bins, app.pdfG, "g" );
                plot (app.CDFPlot, app.bins, app.cdfG, "g" );
                maxG = max(max(app.pdfG));
            end
            
            maxB = 0;
            if valueB == 1
                plot (app.HistogramPlot, app.bins, app.pdfB, "b" );
                plot (app.CDFPlot, app.bins, app.cdfB, "b" );
                maxB = max(max(app.pdfB));
            end
            
             maximumValue = max([ maxR maxG maxB ]);
             app.HistogramPlot.YTick = 0:app.tick:maximumValue+app.tick;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ComputeCDFLabel = uilabel(app.UIFigure);
            app.ComputeCDFLabel.Text = 'Compute CDF';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.CheckBoxRed = uicheckbox(app.UIFigure);
            app.CheckBoxRed.ValueChangedFcn = createCallbackFcn(app, @recreatePlots, true);
            app.CheckBoxRed.Text = 'Red';
            app.CheckBoxRed.Value = true;
            app.CheckBoxGreen = uicheckbox(app.UIFigure);
            app.CheckBoxGreen.ValueChangedFcn = createCallbackFcn(app, @recreatePlots, true);
            app.CheckBoxGreen.Text = 'Green';
            app.CheckBoxGreen.Value = true;
            app.CheckBoxBlue = uicheckbox(app.UIFigure);
            app.CheckBoxBlue.ValueChangedFcn = createCallbackFcn(app, @recreatePlots, true);
            app.CheckBoxBlue.Text = 'Blue';
            app.CheckBoxBlue.Value = true;
            app.CDFPlot = uiaxes(app.UIFigure);
            title(app.CDFPlot, 'CDF')
            xlabel(app.CDFPlot, 'Intensity')
            ylabel(app.CDFPlot, 'Probability')
            zlabel(app.CDFPlot, 'Z')
            app.CDFPlot.XLim = [0 255];
            app.CDFPlot.XTick = [0 15 30 45 60 75 90 105 120 135 150 165 180 195 210 225 240 255];
            app.CDFPlot.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.HistogramPlot = uiaxes(app.UIFigure);
            title(app.HistogramPlot, 'PDF')
            xlabel(app.HistogramPlot, 'Intensity')
            ylabel(app.HistogramPlot, 'Probability')
            zlabel(app.HistogramPlot, 'Z')
            app.HistogramPlot.XLim = [0 255];
            app.HistogramPlot.XTick = [0 15 30 45 60 75 90 105 120 135 150 165 180 195 210 225 240 255];
            app.HistogramPlot.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL3_ComputePdfAndCdfApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end