classdef CeuL3_QuantizeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        QuantizeImageLabel  matlab.ui.control.Label
        ExecuteButton       matlab.ui.control.Button
        PleaseselectthenofbitsinwhichyouwanttoquantizetheimageLabel  matlab.ui.control.Label
        SliderLabel         matlab.ui.control.Label
        Slider              matlab.ui.control.Slider
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
       % value
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            %app.NofbitsEditField.Value = value;
            
               
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h); 
            windowName = app.h.Name; 
            
            figure;
            imshow(I)
            axis off
            title('ORIGINAL IMAGE')
            
            bits = app.Slider.Value;
            levels= 2.^bits;
            quantlevels = max(I(:)); 
            levels2= quantlevels/levels;
            
            
            vector = [1:levels];
            for i= 1:levels
                vector(i) = i*levels2;
                
            end
            
            values = [1:levels+1];
            
            for k= 1
                values(k) = (vector(k)+ 0)/2;
            end
            
            for k= 2:levels
               values(k) = (vector(k) + vector(k-1))/2;
            end
            
            R = I(:,:,1);
            G = I(:,:,2);
            B = I(:,:,3);
            
            quantR = imquantize (R, vector, values);
            quantG  = imquantize (G, vector, values);
            quantB = imquantize (B, vector, values);

            quant=zeros([size(R,1) size(R,2) 3]);
            quant(:,:,1) = quantR;
            quant(:,:,2) = quantG;
            quant(:,:,3) = quantB;
            
            figure;
            imshow(uint8(quant))
            axis off
            title('QUANTIZED IMAGE')


            app.parentApp.updateImageList();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.QuantizeImageLabel = uilabel(app.UIFigure);
            app.QuantizeImageLabel.Text = 'Quantize Image ';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.PleaseselectthenofbitsinwhichyouwanttoquantizetheimageLabel = uilabel(app.UIFigure);
            app.PleaseselectthenofbitsinwhichyouwanttoquantizetheimageLabel.Text = 'Please, select the nº of bits in which you want to quantize the image: ';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 10];
            app.Slider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Slider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL3_QuantizeApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end