classdef CeuL4_HistogramEqualizationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        HistogramEqualizationLabel  matlab.ui.control.Label
        ChooseactionButtonGroup     matlab.ui.container.ButtonGroup
        LuminanceButton             matlab.ui.control.RadioButton
        ColorbandsButton            matlab.ui.control.RadioButton
        ExecuteButton               matlab.ui.control.Button
        ImageLabel                  matlab.ui.control.Label
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = public) %lo he cambiado era privada
        
        function Cp = eq(app, C)
            %contruimos la cdf
            [counts,Nedges] = histcounts(C,[-0.5:1:255.5 ]); %%return the bin counts as a row vector
            cdf = cumsum(counts)/sum(counts); %cumulative sum 
            CDF = (cdf-min(cdf))/(1-min(cdf))*255;
            CDF=uint8(CDF); %convert into unsigned 8-bit integers
            
            
            %le pasamos la imagen y la tabla
            Cp = intlut(C,CDF); %%transformation of values using the lookup table
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            A = getimage(app.h);
            
            %guardamos memoria para nuestra futura imagen
            
            Ap=zeros(size(A));
            windowName = app.h.Name;
            if length(size(A))==3
                R = A(:,:,1);
                G = A(:,:,2);
                B = A(:,:,3);
                
                
                if(app.LuminanceButton.Value==true)
                
                    L=0.299*R+0.587*G+0.114*B;
                    Lp=eq(app, L);
                
                    Ap(:,:,1)=R.*(Lp./L);
                    Ap(:,:,2)=G.*(Lp./L);
                    Ap(:,:,3)=B.*(Lp./L);
                 
                else
                
                %computamos la es sobre cada canal por separado
                Ap(:,:,1)=eq(app,R);
                Ap(:,:,2)=eq(app,G);
                Ap(:,:,3)=eq(app,B);
                
                end
                
                
                figure, imshow(uint8(Ap))
                
                                   
                app.parentApp.updateImageList();
            end
            
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HistogramEqualizationLabel = uilabel(app.UIFigure);
            app.HistogramEqualizationLabel.Text = 'Histogram Equalization';
            app.ChooseactionButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseactionButtonGroup.Title = 'Choose action';
            app.LuminanceButton = uiradiobutton(app.ChooseactionButtonGroup);
            app.LuminanceButton.Text = 'Luminance';
            app.LuminanceButton.Value = true;
            app.ColorbandsButton = uiradiobutton(app.ChooseactionButtonGroup);
            app.ColorbandsButton.Text = 'Color bands';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL4_HistogramEqualizationApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end