classdef CeuL4_HistogramMatchingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HistogramMatchingLabel        matlab.ui.control.Label
        ChooseactionButtonGroup       matlab.ui.container.ButtonGroup
        LuminancetoLuminanceButton    matlab.ui.control.RadioButton
        BandtoBandButton              matlab.ui.control.RadioButton
        LuminancetoBandButton         matlab.ui.control.RadioButton
        ExecuteButton                 matlab.ui.control.Button
        ImageLabel                    matlab.ui.control.Label
        SelectthetargetDropDownLabel  matlab.ui.control.Label
        SelectthetargetDropDown       matlab.ui.control.DropDown
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    methods (Access = public)
        function updateImageList(app)
            figHandles = findobj('Type', 'figure');
            if length(figHandles)>0
                app.SelectthetargetDropDown.Items = {};
                for i=1:length(figHandles)
                    app.SelectthetargetDropDown.Items{i} = figHandles(i).Name;
                end
            end
        end
    end
    
    methods (Access = public)
        function h = getFigureHandler(app)
            h= app.getFigureHandlerByName(app.SelectthetargetDropDown.Value);
        end
    end
    
    methods (Access = public)
        
        function h = getFigureHandlerByName(app, selectedName)
            figHandles = findobj('Type', 'figure');
            for i=1:length(figHandles)
                if strcmp(figHandles(i).Name,selectedName)
                    h=figHandles(i);
                    return
                end
            end
            h = -1;
            return            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            app.updateImageList();
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            targetHandler = app.parentApp.getFigureHandlerByName(app.SelectthetargetDropDown.Value);
            J = getimage(targetHandler);
            
            function L=luminance(I)
                L=uint8(0.299*double(I(:,:,1))+0.587*double(I(:,:,2))+0.114*double(I(:,:,3)));
            end
            
            function CDF=comulativeDensityFunction(I)   
                [r1, c1]=size(I);
                CDF=zeros(256,1);
                RC=r1*c1;
                g=1;
                [count, b]= imhist(I);
                while g<=256
                    a=1;
                    suma=0;
                    while a<=g
                      suma=suma+count(a);
                      a=a+1;
                    end
                   CDF(g)= suma/RC;
                   g=g+1;
                end
            end
            
            function LUT=LookupTable(CDF_j,CDF_i)
                LUT=zeros(256,1);
                gj=0;
                gi=0;
                while gi< 256
                    while CDF_j(gj+1)<CDF_i(gi+1) && gj<255
                        gj=gj+1;
                    end
                    LUT(gi+1)=gj;
                    gi=gi+1;
                end
            end
            
            if app.LuminancetoLuminanceButton.Value==1
                LI = luminance(I);
                LJ = luminance(J);
                CDF_i=comulativeDensityFunction(LI);
                CDF_j=comulativeDensityFunction(LJ);
                LUT= LookupTable(CDF_i,CDF_j);
                B=cast(LUT,'uint8');
                IMG1= intlut(I,B);
                
                figure, imshow(I, []);
                title('Original Image');
                figure, imshow(J, []);
                title('Target Image');
                figure, imshow(IMG1, []);
                title('Final Image');
                
            end
            if app.BandtoBandButton.Value==1
                
                F_bands= imhistmatch(I,J);
                figure, imshow(I, []);
                title('Original Image');
                figure, imshow(J, []);
                title('Target Image');
                figure, imshow(F_bands, []);
                title('Final Image');
            end
            if app.LuminancetoBandButton.Value==1
                LI = luminance(I);
                red=J(:,:,1);
                F_Lband_r= imhistmatch(LI,red);
                green=J(:,:,2);
                F_Lband_g= imhistmatch(LI,green);
                blue=J(:,:,3);
                F_Lband_b= imhistmatch(LI,blue);
                final=cat(3,F_Lband_r,F_Lband_g,F_Lband_b);
                figure, imshow(I, []);
                title('Original Image');
                figure, imshow(J, []);
                title('Target Image');
                figure, imshow(final, []);
                title('Final Image');
            end
        end

        % Callback function
        function LoadimageMenuSelected(app, event)
            [file,path] = uigetfile('*.*','Select an Image');
            if ~isequal(file,0)
               fn=fullfile(path,file);
               [I,map] = imread(fn);
               figure('Name',file);
               imshow(I,map);
               
               app.updateImageList();
            end    
        end

        % Callback function
        function QuitMenuSelected(app, event)
            close all
            closereq
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HistogramMatchingLabel = uilabel(app.UIFigure);
            app.HistogramMatchingLabel.Text = 'Histogram Matching';
            app.ChooseactionButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseactionButtonGroup.Title = 'Choose action';
            app.LuminancetoLuminanceButton = uiradiobutton(app.ChooseactionButtonGroup);
            app.LuminancetoLuminanceButton.Text = 'Luminance to Luminance';
            app.LuminancetoLuminanceButton.Value = true;
            app.BandtoBandButton = uiradiobutton(app.ChooseactionButtonGroup);
            app.BandtoBandButton.Text = 'Band to Band';
            app.LuminancetoBandButton = uiradiobutton(app.ChooseactionButtonGroup);
            app.LuminancetoBandButton.Text = 'Luminance to Band';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.SelectthetargetDropDownLabel = uilabel(app.UIFigure);
            app.SelectthetargetDropDownLabel.Text = 'Select the target';
            app.SelectthetargetDropDown = uidropdown(app.UIFigure);
            app.SelectthetargetDropDown.Items = {};
            app.SelectthetargetDropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL4_HistogramMatchingApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end