classdef CeuL4_IncreaseDecreaseContrastApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        IncreaseDecreaseContrastLabel  matlab.ui.control.Label
        ExporttootherwindowButton      matlab.ui.control.Button
        ImageLabel                     matlab.ui.control.Label
        GREENCHANNELLabel              matlab.ui.control.Label
        REDCHANNELLabel                matlab.ui.control.Label
        PoweredbyMatlabfunctionImadjustappliedtoRGBchannelsLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        RedSlider1                     matlab.ui.control.Slider
        RedSlider2                     matlab.ui.control.Slider
        GreenSlider1                   matlab.ui.control.Slider
        GreenSlider2                   matlab.ui.control.Slider
        BlueSlider1                    matlab.ui.control.Slider
        BlueSlider2                    matlab.ui.control.Slider
        BLUECHANNELLabel               matlab.ui.control.Label
        WarningLabel1                  matlab.ui.control.Label
        LabelPositive                  matlab.ui.control.Label
        LabelNegative                  matlab.ui.control.Label
        WarningLabel2                  matlab.ui.control.Label
        WarningLabel3                  matlab.ui.control.Label
        WarningExport                  matlab.ui.control.Label
        JrcMjmjxIrcmiMimimjLabel       matlab.ui.control.Label
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            app.Image.ImageSource=app.h.Name;
        end

        % Button pushed function: ExporttootherwindowButton
        function ExporttootherwindowButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            
            rlow=0;
            rhigh=1;
            glow=0;
            ghigh=1;
            blow=0;
            bhigh=1;
            
            if app.RedSlider1.Value > app.RedSlider2.Value || app.GreenSlider1.Value>app.GreenSlider2.Value || app.BlueSlider1.Value>app.BlueSlider2.Value
                app.WarningExport.Text=strcat("Operation not permitted, look the orange warnings");
            else
                app.WarningExport.Text=strcat(" ");
                rlow=app.RedSlider1.Value;
                rhigh=app.RedSlider2.Value;
                glow=app.GreenSlider1.Value;
                ghigh=app.GreenSlider2.Value;
                blow=app.BlueSlider1.Value;
                bhigh=app.BlueSlider2.Value;
                K = imadjust(I,[rlow glow blow; rhigh ghigh bhigh],[]);
                hr = figure('Name',strcat(windowName," (contrast)"));
                imshow(K);
                figure(hr);

            end
        
            disp('hello');
%bermejo4
        end

        % Value changed function: RedSlider1
        function RedSlider1ValueChanged(app, event)
            value = app.RedSlider1.Value;
            
            I = getimage(app.h);
            windowName = app.h.Name;
            
            rlow=0;
            rhigh=1;
            glow=0;
            ghigh=1;
            blow=0;
            bhigh=1;
            
            if app.RedSlider1.Value>app.RedSlider2.Value
                app.WarningLabel1.Text=strcat(" The value of the first column must be lower than the second column in the red channel");
                rlow=0;
                rhigh=1;
            end
            if app.RedSlider1.Value<app.RedSlider2.Value
                app.WarningLabel1.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                rlow=app.RedSlider1.Value;
                rhigh=app.RedSlider2.Value;
            end
            if app.GreenSlider1.Value>app.GreenSlider2.Value
                app.WarningLabel2.Text=strcat(" The value of the first column must be lower than the second column in the green channel");
                glow=0;
                ghigh=1;
            end
            if app.GreenSlider1.Value<app.GreenSlider2.Value
                app.WarningLabel2.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                glow=app.GreenSlider1.Value;
                ghigh=app.GreenSlider2.Value;
            end
            if app.BlueSlider1.Value>app.BlueSlider2.Value
                app.WarningLabel3.Text=strcat(" The value of the first column must be lower than the second column in the blue channel");
                blow=0;
                bhigh=1;
            end
            if app.BlueSlider1.Value<app.BlueSlider2.Value
                app.WarningLabel3.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                blow=app.BlueSlider1.Value;
                bhigh=app.BlueSlider2.Value;
            end
            
            K = imadjust(I,[rlow glow blow; rhigh ghigh bhigh],[]);
            app.Image.ImageSource=K;
            
        end

        % Value changed function: RedSlider2
        function RedSlider2ValueChanged(app, event)
            value = app.RedSlider2.Value;
            
            I = getimage(app.h);
            windowName = app.h.Name;
            
            rlow=0;
            rhigh=1;
            glow=0;
            ghigh=1;
            blow=0;
            bhigh=1;
            
            if app.RedSlider1.Value>app.RedSlider2.Value
                app.WarningLabel1.Text=strcat(" The value of the first column must be lower than the second column in the red channel");
                rlow=0;
                rhigh=1;
            end
            if app.RedSlider1.Value<app.RedSlider2.Value
                app.WarningLabel1.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                rlow=app.RedSlider1.Value;
                rhigh=app.RedSlider2.Value;
            end
            if app.GreenSlider1.Value>app.GreenSlider2.Value
                app.WarningLabel2.Text=strcat(" The value of the first column must be lower than the second column in the green channel");
                glow=0;
                ghigh=1;
            end
            if app.GreenSlider1.Value<app.GreenSlider2.Value
                app.WarningLabel2.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                glow=app.GreenSlider1.Value;
                ghigh=app.GreenSlider2.Value;
            end
            if app.BlueSlider1.Value>app.BlueSlider2.Value
                app.WarningLabel3.Text=strcat(" The value of the first column must be lower than the second column in the blue channel");
                blow=0;
                bhigh=1;
            end
            if app.BlueSlider1.Value<app.BlueSlider2.Value
                app.WarningLabel3.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                blow=app.BlueSlider1.Value;
                bhigh=app.BlueSlider2.Value;
            end
            
            K = imadjust(I,[rlow glow blow; rhigh ghigh bhigh],[]);
            app.Image.ImageSource=K;
            
        end

        % Value changed function: GreenSlider1
        function GreenSlider1ValueChanged(app, event)
            value = app.GreenSlider1.Value;
            
             I = getimage(app.h);
            windowName = app.h.Name;
            
            rlow=0;
            rhigh=1;
            glow=0;
            ghigh=1;
            blow=0;
            bhigh=1;
            
            if app.RedSlider1.Value>app.RedSlider2.Value
                app.WarningLabel1.Text=strcat(" The value of the first column must be lower than the second column in the red channel");
                rlow=0;
                rhigh=1;
            end
            if app.RedSlider1.Value<app.RedSlider2.Value
                app.WarningLabel1.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                rlow=app.RedSlider1.Value;
                rhigh=app.RedSlider2.Value;
            end
            if app.GreenSlider1.Value>app.GreenSlider2.Value
                app.WarningLabel2.Text=strcat(" The value of the first column must be lower than the second column in the green channel");
                glow=0;
                ghigh=1;
            end
            if app.GreenSlider1.Value<app.GreenSlider2.Value
                app.WarningLabel2.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                glow=app.GreenSlider1.Value;
                ghigh=app.GreenSlider2.Value;
            end
            if app.BlueSlider1.Value>app.BlueSlider2.Value
                app.WarningLabel3.Text=strcat(" The value of the first column must be lower than the second column in the blue channel");
                blow=0;
                bhigh=1;
            end
            if app.BlueSlider1.Value<app.BlueSlider2.Value
                app.WarningLabel3.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                blow=app.BlueSlider1.Value;
                bhigh=app.BlueSlider2.Value;
            end
            
            K = imadjust(I,[rlow glow blow; rhigh ghigh bhigh],[]);
            app.Image.ImageSource=K;
            
        end

        % Value changed function: GreenSlider2
        function GreenSlider2ValueChanged(app, event)
            value = app.GreenSlider2.Value;
            
            I = getimage(app.h);
            windowName = app.h.Name;
            
            rlow=0;
            rhigh=1;
            glow=0;
            ghigh=1;
            blow=0;
            bhigh=1;
            
            if app.RedSlider1.Value>app.RedSlider2.Value
                app.WarningLabel1.Text=strcat(" The value of the first column must be lower than the second column in the red channel");
                rlow=0;
                rhigh=1;
            end
            if app.RedSlider1.Value<app.RedSlider2.Value
                app.WarningLabel1.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                rlow=app.RedSlider1.Value;
                rhigh=app.RedSlider2.Value;
            end
            if app.GreenSlider1.Value>app.GreenSlider2.Value
                app.WarningLabel2.Text=strcat(" The value of the first column must be lower than the second column in the green channel");
                glow=0;
                ghigh=1;
            end
            if app.GreenSlider1.Value<app.GreenSlider2.Value
                app.WarningLabel2.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                glow=app.GreenSlider1.Value;
                ghigh=app.GreenSlider2.Value;
            end
            if app.BlueSlider1.Value>app.BlueSlider2.Value
                app.WarningLabel3.Text=strcat(" The value of the first column must be lower than the second column in the blue channel");
                blow=0;
                bhigh=1;
            end
            if app.BlueSlider1.Value<app.BlueSlider2.Value
                app.WarningLabel3.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                blow=app.BlueSlider1.Value;
                bhigh=app.BlueSlider2.Value;
            end
            
            K = imadjust(I,[rlow glow blow; rhigh ghigh bhigh],[]);
            app.Image.ImageSource=K;
        end

        % Value changed function: BlueSlider1
        function BlueSlider1ValueChanged(app, event)
            value = app.BlueSlider1.Value;
            
             I = getimage(app.h);
            windowName = app.h.Name;
            
            rlow=0;
            rhigh=1;
            glow=0;
            ghigh=1;
            blow=0;
            bhigh=1;
            
            if app.RedSlider1.Value>app.RedSlider2.Value
                app.WarningLabel1.Text=strcat(" The value of the first column must be lower than the second column in the red channel");
                rlow=0;
                rhigh=1;
            end
            if app.RedSlider1.Value<app.RedSlider2.Value
                app.WarningLabel1.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                rlow=app.RedSlider1.Value;
                rhigh=app.RedSlider2.Value;
            end
            if app.GreenSlider1.Value>app.GreenSlider2.Value
                app.WarningLabel2.Text=strcat(" The value of the first column must be lower than the second column in the green channel");
                glow=0;
                ghigh=1;
            end
            if app.GreenSlider1.Value<app.GreenSlider2.Value
                app.WarningLabel2.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                glow=app.GreenSlider1.Value;
                ghigh=app.GreenSlider2.Value;
            end
            if app.BlueSlider1.Value>app.BlueSlider2.Value
                app.WarningLabel3.Text=strcat(" The value of the first column must be lower than the second column in the blue channel");
                blow=0;
                bhigh=1;
            end
            if app.BlueSlider1.Value<app.BlueSlider2.Value
                app.WarningLabel3.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                blow=app.BlueSlider1.Value;
                bhigh=app.BlueSlider2.Value;
            end
            
            K = imadjust(I,[rlow glow blow; rhigh ghigh bhigh],[]);
            app.Image.ImageSource=K;
        end

        % Value changed function: BlueSlider2
        function BlueSlider2ValueChanged(app, event)
            value = app.BlueSlider2.Value;
            
             I = getimage(app.h);
            windowName = app.h.Name;
            
            rlow=0;
            rhigh=1;
            glow=0;
            ghigh=1;
            blow=0;
            bhigh=1;
            
            if app.RedSlider1.Value>app.RedSlider2.Value
                app.WarningLabel1.Text=strcat(" The value of the first column must be lower than the second column in the red channel");
                rlow=0;
                rhigh=1;
            end
            if app.RedSlider1.Value<app.RedSlider2.Value
                app.WarningLabel1.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                rlow=app.RedSlider1.Value;
                rhigh=app.RedSlider2.Value;
            end
            if app.GreenSlider1.Value>app.GreenSlider2.Value
                app.WarningLabel2.Text=strcat(" The value of the first column must be lower than the second column in the green channel");
                glow=0;
                ghigh=1;
            end
            if app.GreenSlider1.Value<app.GreenSlider2.Value
                app.WarningLabel2.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                glow=app.GreenSlider1.Value;
                ghigh=app.GreenSlider2.Value;
            end
            if app.BlueSlider1.Value>app.BlueSlider2.Value
                app.WarningLabel3.Text=strcat(" The value of the first column must be lower than the second column in the blue channel");
                blow=0;
                bhigh=1;
            end
            if app.BlueSlider1.Value<app.BlueSlider2.Value
                app.WarningLabel3.Text=strcat(" ");
                app.WarningExport.Text=strcat(" ");
                blow=app.BlueSlider1.Value;
                bhigh=app.BlueSlider2.Value;
            end
            
            K = imadjust(I,[rlow glow blow; rhigh ghigh bhigh],[]);
            app.Image.ImageSource=K;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IncreaseDecreaseContrastLabel = uilabel(app.UIFigure);
            app.IncreaseDecreaseContrastLabel.Text = 'Increase/Decrease Contrast:';
            app.ExporttootherwindowButton = uibutton(app.UIFigure, 'push');
            app.ExporttootherwindowButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttootherwindowButtonPushed, true);
            app.ExporttootherwindowButton.Text = 'Export to other window';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.GREENCHANNELLabel = uilabel(app.UIFigure);
            app.GREENCHANNELLabel.Text = 'GREEN CHANNEL';
            app.REDCHANNELLabel = uilabel(app.UIFigure);
            app.REDCHANNELLabel.Text = 'RED CHANNEL';
            app.PoweredbyMatlabfunctionImadjustappliedtoRGBchannelsLabel = uilabel(app.UIFigure);
            app.PoweredbyMatlabfunctionImadjustappliedtoRGBchannelsLabel.Text = {'Powered by'; 'Matlab function "Imadjust" applied to RGB channels'; ''};
            app.Image = uiimage(app.UIFigure);
            app.RedSlider1 = uislider(app.UIFigure);
            app.RedSlider1.Limits = [0 1];
            app.RedSlider1.ValueChangedFcn = createCallbackFcn(app, @RedSlider1ValueChanged, true);
            app.RedSlider2 = uislider(app.UIFigure);
            app.RedSlider2.Limits = [0 1];
            app.RedSlider2.ValueChangedFcn = createCallbackFcn(app, @RedSlider2ValueChanged, true);
            app.RedSlider2.Value = 1;
            app.GreenSlider1 = uislider(app.UIFigure);
            app.GreenSlider1.Limits = [0 1];
            app.GreenSlider1.ValueChangedFcn = createCallbackFcn(app, @GreenSlider1ValueChanged, true);
            app.GreenSlider2 = uislider(app.UIFigure);
            app.GreenSlider2.Limits = [0 1];
            app.GreenSlider2.ValueChangedFcn = createCallbackFcn(app, @GreenSlider2ValueChanged, true);
            app.GreenSlider2.Value = 1;
            app.BlueSlider1 = uislider(app.UIFigure);
            app.BlueSlider1.Limits = [0 1];
            app.BlueSlider1.ValueChangedFcn = createCallbackFcn(app, @BlueSlider1ValueChanged, true);
            app.BlueSlider2 = uislider(app.UIFigure);
            app.BlueSlider2.Limits = [0 1];
            app.BlueSlider2.ValueChangedFcn = createCallbackFcn(app, @BlueSlider2ValueChanged, true);
            app.BlueSlider2.Value = 1;
            app.BLUECHANNELLabel = uilabel(app.UIFigure);
            app.BLUECHANNELLabel.Text = 'BLUE CHANNEL';
            app.WarningLabel1 = uilabel(app.UIFigure);
            app.WarningLabel1.Text = '- ';
            app.LabelPositive = uilabel(app.UIFigure);
            app.LabelPositive.Text = '+';
            app.LabelNegative = uilabel(app.UIFigure);
            app.LabelNegative.Text = '-';
            app.WarningLabel2 = uilabel(app.UIFigure);
            app.WarningLabel2.Text = '- ';
            app.WarningLabel3 = uilabel(app.UIFigure);
            app.WarningLabel3.Text = '- ';
            app.WarningExport = uilabel(app.UIFigure);
            app.WarningExport.Text = '';
            app.JrcMjmjxIrcmiMimimjLabel = uilabel(app.UIFigure);
            app.JrcMjmjxIrcmiMimimjLabel.Text = 'J(r,c) = (Mj-mj) x ( (I(r,c) - mi) / (Mi-mi) ) + mj';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL4_IncreaseDecreaseContrastApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end