classdef CeuL5_SaturationChangesApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SaturationChangeLabel  matlab.ui.control.Label
        ChooseavalueforthesaturationButtonGroup  matlab.ui.container.ButtonGroup
        SaturationchangeLabel  matlab.ui.control.Label
        saturationSlider       matlab.ui.control.Slider
        ImageLabel             matlab.ui.control.Label
        ShowImage              matlab.ui.control.UIAxes
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
        I
        imag
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);

            I = getimage(app.h);
            image(app.ShowImage,I);
            axis(app.ShowImage,'off');            
        end

        % Value changed function: saturationSlider
        function saturationSliderValueChanged(app, event)
            I = getimage(app.h);
            
            saturationChange = app.saturationSlider.Value;
            hsv=rgb2hsv(I); %this is the hsv of the original image
            HSV=hsv; %this is the HSV of the image that we are modifying
            saturationValue=(1+saturationChange)/100;
            newS=hsv(:,:,2)*saturationValue;   %we are multiplying with the new value of saturation
            newS(newS>1)=1;
            HSV(:,:,2)=newS;
            I=hsv2rgb(HSV); %estamos recuperando la imagen con los nuevos valores de saturación en RGB
                
            %app.parentApp.updateImageList();       
            image(app.ShowImage,I);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SaturationChangeLabel = uilabel(app.UIFigure);
            app.SaturationChangeLabel.Text = 'Saturation Change';
            app.ChooseavalueforthesaturationButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseavalueforthesaturationButtonGroup.Title = 'Choose a value for the saturation';
            app.SaturationchangeLabel = uilabel(app.ChooseavalueforthesaturationButtonGroup);
            app.SaturationchangeLabel.Text = 'Saturation change';
            app.saturationSlider = uislider(app.ChooseavalueforthesaturationButtonGroup);
            app.saturationSlider.Limits = [-50 50];
            app.saturationSlider.ValueChangedFcn = createCallbackFcn(app, @saturationSliderValueChanged, true);
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.ShowImage = uiaxes(app.UIFigure);
            title(app.ShowImage, 'New Saturation')
            xlabel(app.ShowImage, 'X')
            ylabel(app.ShowImage, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL5_SaturationChangesApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end