classdef CeuL6_HSVconversionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ExecuteHSVconversionButton  matlab.ui.control.Button
        ImageLabel                  matlab.ui.control.Label
        HSVconversionLabel          matlab.ui.control.Label
        ShowRGBimagesButton         matlab.ui.control.Button
    end

    % Author: María Montes 
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
        end

        % Button pushed function: ExecuteHSVconversionButton
        function ExecuteHSVconversionButtonPushed(app, event)
             I = getimage(app.h);
            windowName = app.h.Name;
            if length(size(I))==3
                R = I(:,:,1);
                G = I(:,:,2);
                B = I(:,:,3);
                
                %CONVERSION
                HSV = rgb2hsv(I);
                [h s v] = imsplit(HSV);
                if length(size(HSV))==3
                h = HSV(:,:,1);
                s = HSV(:,:,2);
                v = HSV(:,:,3);
                
%                 hh = figure('Name',strcat(windowName," (Hue)"));
%                 imshow(h);
%                 hs = figure('Name',strcat(windowName," (Saturation)"));
%                 imshow(s);
%                 hv = figure('Name',strcat(windowName," (Value)"));
%                 imshow(v);
%                 figure; 
                %montage({h, s, v, HSV}, 'BorderSize', 10);
                
                %REPRESENTATION
                fig = figure(); 
                tlo = tiledlayout(fig,2,2,'TileSpacing','None');
                    %squares
                    ax1 = nexttile(tlo); 
                    imshow(h,'Parent',ax1)
                    title (['Hue'])
                    
                    ax2 = nexttile(tlo); 
                    imshow(s,'Parent',ax2)
                    title (['Saturation'])
                    
                    ax3 = nexttile(tlo);
                    imshow(v,'Parent',ax3)
                    title (['Value'])
                    
                    ax4 = nexttile(tlo);
                    imshow(HSV,'Parent',ax4)
                    title (['HSV Image (interpreted as RGB)'])
                    
                  title(tlo,'HSV Conversion')
                  end
                        
             end
                app.parentApp.updateImageList();
       
        end

        % Button pushed function: ShowRGBimagesButton
        function ShowRGBimagesButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            if length(size(I))==3
                R = I(:,:,1);
                G = I(:,:,2);
                B = I(:,:,3);                
                       
                %REPRESENTATION
                fig = figure(); 
                tlo = tiledlayout(fig,2,2,'TileSpacing','None'); 

                    %squares
                    ax1 = nexttile(tlo); 
                    
                    IR=uint8(zeros(size(I)));
                    IR(:,:,1)=R;
                    image(IR,'Parent',ax1)
                    %colormap([l z z]);
                    title (['Red'])
                    
                    ax2 = nexttile(tlo); 
                    IG=uint8(zeros(size(I)));
                    IG(:,:,2)=G;
                    %colormap([z l z]);
                    image(IG,'Parent',ax2) 
                    title (['Green'])
                    
                    ax3 = nexttile(tlo);
                    IB=uint8(zeros(size(I)));
                    IB(:,:,3)=B;
                    image(IB,'Parent',ax3)
                    %colormap([z z l]);
                    title (['Blue'])
                    
                    ax4 = nexttile(tlo);
                    imshow(I,'Parent',ax4)
                    title (['Original Image'])
                    
                    title(tlo,'RGB Image Representation')
                    
                %figure;
                %montage({hr, hg,hb, I}, 'BorderSize', 10);                         
             end
                app.parentApp.updateImageList();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExecuteHSVconversionButton = uibutton(app.UIFigure, 'push');
            app.ExecuteHSVconversionButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteHSVconversionButtonPushed, true);
            app.ExecuteHSVconversionButton.Text = 'Execute HSV conversion';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.HSVconversionLabel = uilabel(app.UIFigure);
            app.HSVconversionLabel.Text = 'HSV conversion';
            app.ShowRGBimagesButton = uibutton(app.UIFigure, 'push');
            app.ShowRGBimagesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRGBimagesButtonPushed, true);
            app.ShowRGBimagesButton.Text = {'Show RGB images'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL6_HSVconversionApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end