classdef CeuL8_KernelSpatialConvolutionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ConvolutionKernelMaskOperationLabel  matlab.ui.control.Label
        PanelKernel                 matlab.ui.container.Panel
        GridLayoutKernel            matlab.ui.container.GridLayout
        kernel_1_1                  matlab.ui.control.NumericEditField
        kernel_1_2                  matlab.ui.control.NumericEditField
        kernel_1_3                  matlab.ui.control.NumericEditField
        kernel_2_1                  matlab.ui.control.NumericEditField
        kernel_2_2                  matlab.ui.control.NumericEditField
        kernel_2_3                  matlab.ui.control.NumericEditField
        kernel_3_1                  matlab.ui.control.NumericEditField
        kernel_3_2                  matlab.ui.control.NumericEditField
        kernel_3_3                  matlab.ui.control.NumericEditField
        KerneltimesImagePanel       matlab.ui.container.Panel
        GridLayoutKernelTimesImage  matlab.ui.container.GridLayout
        times_1_1                   matlab.ui.control.NumericEditField
        times_1_2                   matlab.ui.control.NumericEditField
        times_1_3                   matlab.ui.control.NumericEditField
        times_2_1                   matlab.ui.control.NumericEditField
        times_2_2                   matlab.ui.control.NumericEditField
        times_2_3                   matlab.ui.control.NumericEditField
        times_3_1                   matlab.ui.control.NumericEditField
        times_3_2                   matlab.ui.control.NumericEditField
        times_3_3                   matlab.ui.control.NumericEditField
        ChooseaKernelButtonGroup    matlab.ui.container.ButtonGroup
        AutoCheckBox                matlab.ui.control.CheckBox
        KernelDropDownLabel         matlab.ui.control.Label
        KernelDropDown              matlab.ui.control.DropDown
        ManualButton                matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        ImagePanel                  matlab.ui.container.Panel
        GridLayoutImage             matlab.ui.container.GridLayout
        image_1_2                   matlab.ui.control.NumericEditField
        image_1_1                   matlab.ui.control.NumericEditField
        image_1_3                   matlab.ui.control.NumericEditField
        image_2_1                   matlab.ui.control.NumericEditField
        image_2_2                   matlab.ui.control.NumericEditField
        image_2_3                   matlab.ui.control.NumericEditField
        image_3_1                   matlab.ui.control.NumericEditField
        image_3_2                   matlab.ui.control.NumericEditField
        image_3_3                   matlab.ui.control.NumericEditField
        image_1_4                   matlab.ui.control.NumericEditField
        image_1_5                   matlab.ui.control.NumericEditField
        image_2_4                   matlab.ui.control.NumericEditField
        image_2_5                   matlab.ui.control.NumericEditField
        image_3_4                   matlab.ui.control.NumericEditField
        image_3_5                   matlab.ui.control.NumericEditField
        image_4_1                   matlab.ui.control.NumericEditField
        image_4_2                   matlab.ui.control.NumericEditField
        image_4_3                   matlab.ui.control.NumericEditField
        image_4_4                   matlab.ui.control.NumericEditField
        image_4_5                   matlab.ui.control.NumericEditField
        image_5_1                   matlab.ui.control.NumericEditField
        image_5_2                   matlab.ui.control.NumericEditField
        image_5_3                   matlab.ui.control.NumericEditField
        image_5_4                   matlab.ui.control.NumericEditField
        image_5_5                   matlab.ui.control.NumericEditField
        OutputPanel                 matlab.ui.container.Panel
        GridLayoutImage_2           matlab.ui.container.GridLayout
        output_1_1                  matlab.ui.control.NumericEditField
        output_1_3                  matlab.ui.control.NumericEditField
        output_2_1                  matlab.ui.control.NumericEditField
        output_2_2                  matlab.ui.control.NumericEditField
        output_2_3                  matlab.ui.control.NumericEditField
        output_3_1                  matlab.ui.control.NumericEditField
        output_3_2                  matlab.ui.control.NumericEditField
        output_3_3                  matlab.ui.control.NumericEditField
        output_1_4                  matlab.ui.control.NumericEditField
        output_1_5                  matlab.ui.control.NumericEditField
        output_2_4                  matlab.ui.control.NumericEditField
        output_2_5                  matlab.ui.control.NumericEditField
        output_3_4                  matlab.ui.control.NumericEditField
        output_3_5                  matlab.ui.control.NumericEditField
        output_4_1                  matlab.ui.control.NumericEditField
        output_4_2                  matlab.ui.control.NumericEditField
        output_4_3                  matlab.ui.control.NumericEditField
        output_4_4                  matlab.ui.control.NumericEditField
        output_4_5                  matlab.ui.control.NumericEditField
        output_5_1                  matlab.ui.control.NumericEditField
        output_5_2                  matlab.ui.control.NumericEditField
        output_5_3                  matlab.ui.control.NumericEditField
        output_5_4                  matlab.ui.control.NumericEditField
        output_5_5                  matlab.ui.control.NumericEditField
        output_1_2                  matlab.ui.control.NumericEditField
        Panel                       matlab.ui.container.Panel
        InputImagePanel             matlab.ui.container.Panel
        InputImage                  matlab.ui.control.UIAxes
        OutputImagePanel            matlab.ui.container.Panel
        OutputImage                 matlab.ui.control.UIAxes
        CalculusPanel               matlab.ui.container.Panel
        GridLayoutCalculus          matlab.ui.container.GridLayout
        Label                       matlab.ui.control.Label
        EditField                   matlab.ui.control.EditField
        Label_2                     matlab.ui.control.Label
        EditField_2                 matlab.ui.control.EditField
        Label_3                     matlab.ui.control.Label
        EditField_3                 matlab.ui.control.EditField
        Label_4                     matlab.ui.control.Label
        EditField_4                 matlab.ui.control.EditField
        Label_5                     matlab.ui.control.Label
        EditField_5                 matlab.ui.control.EditField
        Label_6                     matlab.ui.control.Label
        EditField_6                 matlab.ui.control.EditField
        Label_7                     matlab.ui.control.Label
        EditField_7                 matlab.ui.control.EditField
        Label_8                     matlab.ui.control.Label
        EditField_8                 matlab.ui.control.EditField
        Label_9                     matlab.ui.control.Label
        EditField_9                 matlab.ui.control.EditField
        TotalscaledEditFieldLabel   matlab.ui.control.Label
        TotalscaledEditField        matlab.ui.control.EditField
        TotalEditFieldLabel         matlab.ui.control.Label
        TotalEditField              matlab.ui.control.EditField
        AutospeedsecSlider          matlab.ui.control.Slider
        AutoSpeedLabel              matlab.ui.control.Label
    end

    % Author: Gonzalo Calvo De Andrés  
    
    properties (Access = private)
        parentApp % parent app
        CYAN = [0.06 1.00 1.00];
        GREEN = [0.39 0.83 0.07];
        YELLOW = [1.00 1.00 0.00];
        WHITE = [1.00 1.00 1.00];
        
       iteration = 0;
       SIZE_KERNEL = 3
       SIZE_PICTURE = 5
       
       maxOutput = 0;
       minOutput = 0;
       
       autoPeriod = 4;
       t = timer;
    end
    
    methods (Access = public)
        
        function [maxValue, minValue] = calcularMaxMin(app)
            maxValue = 0;
            minValue = 0;
            
            numbers = transpose(reshape(app.GridLayoutImage.Children(), app.SIZE_PICTURE, app.SIZE_PICTURE));
            kernel = transpose(reshape(app.GridLayoutKernel.Children(), app.SIZE_KERNEL, app.SIZE_KERNEL));
            
            for i = 0 : 2
                for j = 0 : 2
                    result = 0;
                    for k = 1: app.SIZE_KERNEL
                        for l = 1: app.SIZE_KERNEL
                            result = result+numbers(i+k,j+l).Value*kernel(k,l).Value;
                        end
                    end
                    if ( result > maxValue)
                        maxValue = result;
                    elseif ( result < minValue)
                        minValue = result;
                    end
                end
            end           
            
        end
        
        function Reset(app)
            if isvalid(app.t)
                app.t = timer('StartDelay', 4, 'Period', app.autoPeriod, 'TasksToExecute', 9, ...
          'ExecutionMode', 'fixedRate');
                app.t.TimerFcn = @(~, thisEvent)NextStep(app);
            end
            app.AutoCheckBox.Value = 0;
            
            app.iteration = 0;
            
            app.ManualButton.Enable = true;
            
            GetKernelValues(app);
            
            numbers = transpose(reshape(app.GridLayoutImage.Children(), app.SIZE_PICTURE, app.SIZE_PICTURE));
            number_row_columns = size(numbers);            
            
            for n = 1 : number_row_columns(1,1)
                for k = 1 : number_row_columns(1,2)
                    numbers(n, k).BackgroundColor = app.WHITE;
                    numbers(n, k).Value = round(rand*255);
                end
            end
            
            for i = [0,app.SIZE_KERNEL-1]
                for j = [1,app.SIZE_KERNEL]
                    app.GridLayoutKernelTimesImage.Children(i*app.SIZE_KERNEL+j).BackgroundColor = app.CYAN;
                    app.GridLayoutKernel.Children(i*app.SIZE_KERNEL+j).BackgroundColor = app.CYAN;
                end
            end
            
            for i = 1:4
                app.GridLayoutKernelTimesImage.Children(2*i).BackgroundColor = app.GREEN;
                app.GridLayoutKernel.Children(2*i).BackgroundColor = app.GREEN;
            end
            
            
            app.kernel_2_2.BackgroundColor = app.YELLOW;
            app.times_2_2.BackgroundColor = app.YELLOW;  
            
            %Input matrix
            img = reshape([numbers.Value], app.SIZE_PICTURE, app.SIZE_PICTURE);
            
            %InputUmage
            colormap(app.InputImage, gray(255));
            im = image (app.InputImage, img);  
            
            %Output matrix
            children = transpose(reshape(app.GridLayoutImage_2.Children(), app.SIZE_PICTURE, app.SIZE_PICTURE));
            img = reshape(children, app.SIZE_PICTURE, app.SIZE_PICTURE);
            number_row_columns = size(children);
            
            for n = 1 : number_row_columns(1,1)
                for k = 1 : number_row_columns(1,2)
                    children(n, k).BackgroundColor = app.WHITE;
                    children(n, k).Value = 0;
                end
            end 
            
            %Output image
            colormap(app.OutputImage, gray(255));
            im = image (app.OutputImage, [img.Value]);
            
            %KernelTimesImage Matrix
            children = transpose(reshape(app.GridLayoutKernelTimesImage.Children(), app.SIZE_KERNEL, app.SIZE_KERNEL));
            number_row_columns = size(children);
            
            for n = 1 : number_row_columns(1,1)
                for k = 1 : number_row_columns(1,2)
                    children(n, k).Value = 0;
                end
            end 
            
            %kernel times image calculus
            children = transpose(reshape(app.GridLayoutCalculus.Children(), 2, []));
            s = size(children);
            for n = 1 : s(1,1)
                children(n, 2).Value = '0';
            end 
            
            [app.maxOutput, app.minOutput] = calcularMaxMin(app);
            
        end
        
        function numEscalado = escalar(app, num, max, min)
            numEscalado = 255*(num - min)/(max-min);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            clc
            app.parentApp = parentApp;        
            Reset(app);
            
        end

        % Button pushed function: ManualButton
        function NextStep(app, event)
            numbers = transpose(reshape(app.GridLayoutImage.Children(), app.SIZE_PICTURE, app.SIZE_PICTURE));
            number_row_columns = size(numbers);
            
            kernel = transpose(reshape(app.GridLayoutKernel.Children(), app.SIZE_KERNEL, app.SIZE_KERNEL));
            kernel_times_image = transpose(reshape(app.GridLayoutKernelTimesImage.Children(), app.SIZE_KERNEL, app.SIZE_KERNEL));
            output = transpose(reshape(app.GridLayoutImage_2.Children(), app.SIZE_PICTURE, app.SIZE_PICTURE));
            
            
            for n = 1 : number_row_columns(1,1)
                for k = 1 : number_row_columns(1,2)
                    numbers(n, k).BackgroundColor = app.WHITE;
                end
            end
            i = floor(app.iteration/(app.SIZE_PICTURE - app.SIZE_KERNEL + 1));
            j = mod(app.iteration, app.SIZE_KERNEL);
            
            if i+app.SIZE_KERNEL <= number_row_columns(1,1) && j+app.SIZE_KERNEL <=  number_row_columns(1,2) 
                
                numbers(i+1, j+1).BackgroundColor = app.CYAN;
                numbers(i+1, j + app.SIZE_KERNEL).BackgroundColor = app.CYAN;
                numbers(i + app.SIZE_KERNEL, j+1).BackgroundColor = app.CYAN;
                numbers(i + app.SIZE_KERNEL, j + app.SIZE_KERNEL).BackgroundColor = app.CYAN;
                
                numbers(i+1, j+2).BackgroundColor = app.GREEN;
                numbers(i+2, j+1).BackgroundColor = app.GREEN;
                numbers(i+2, j+3).BackgroundColor = app.GREEN;
                numbers(i+3, j+2).BackgroundColor = app.GREEN;
                
                numbers(i+2, j+2).BackgroundColor = app.YELLOW;
                
                output_value = 0;
                calculus = transpose(reshape(app.GridLayoutCalculus.Children(), 2, []));
                
                for k = 1: app.SIZE_KERNEL
                    for l = 1 : app.SIZE_KERNEL
                        to_add = numbers(i+k,j+l).Value*kernel(k,l).Value;
                        kernel_times_image(k,l).Value = to_add;
                        calculus(l*k, 2).Value = sprintf('%0.0f', to_add);
                        output_value = output_value + to_add;
                    end
                end
                calculus(11,2).Value = sprintf('%0.0f', output_value);
                
                output_value = escalar(app, output_value, app.maxOutput, app.minOutput);
                
                calculus(10,2).Value = sprintf('%0.0f', output_value);
                
                output(i+2, j+2).BackgroundColor = app.YELLOW;
                output(i+2, j+2).Value = round(output_value);
                
                
                app.iteration = app.iteration + 1;
                
                children = transpose(reshape(app.GridLayoutImage_2.Children(), app.SIZE_PICTURE, app.SIZE_PICTURE));
                img = reshape([children.Value], app.SIZE_PICTURE, app.SIZE_PICTURE);
                
                colormap(app.OutputImage, gray(255));
                im = image (app.OutputImage, img);
                
            else
                app.ManualButton.Enable = false;
            end
            
        end

        % Button pushed function: ResetButton
        function reset(app, event)
            Reset(app)
        end

        % Value changed function: KernelDropDown
        function GetKernelValues(app, event)
            value = app.KernelDropDown.Value;
            children = transpose(reshape(app.GridLayoutKernel.Children(), app.SIZE_KERNEL, app.SIZE_KERNEL));
            if value == "Laplacian Kernel"
                children(1,1).Value = -1;
                children(1,2).Value = -1;
                children(1,3).Value = -1;
                children(2,1).Value = -1;
                children(2,2).Value = 8;
                children(2,3).Value = -1;
                children(3,1).Value = -1;
                children(3,2).Value = -1;
                children(3,3).Value = -1;
            elseif value == "Sharpening Kernel"
                children(1,1).Value = 0;
                children(1,2).Value = -1;
                children(1,3).Value = 0;
                children(2,1).Value = -1;
                children(2,2).Value = 5;
                children(2,3).Value = -1;
                children(3,1).Value = 0;
                children(3,2).Value = -1;
                children(3,3).Value = 0;
            elseif value == "Vertical Derivative"
                children(1,1).Value = 1;
                children(1,2).Value = 2;
                children(1,3).Value = 1;
                children(2,1).Value = 0;
                children(2,2).Value = 0;
                children(2,3).Value = 0;
                children(3,1).Value = -1;
                children(3,2).Value = 2;
                children(3,3).Value = -1;
            elseif value == "Smoothing Kernel"
                children(1,1).Value = 1;
                children(1,2).Value = 1;
                children(1,3).Value = 1;
                children(2,1).Value = 1;
                children(2,2).Value = 1;
                children(2,3).Value = 1;
                children(3,1).Value = 1;
                children(3,2).Value = 1;
                children(3,3).Value = 1;
            end
        end

        % Value changed function: AutoCheckBox
        function startAuto(app, event)
            value = app.AutoCheckBox.Value;
            if value == 0
                stop(app.t)
            elseif value == 1
                start(app.t)
            end
        end

        % Value changed function: AutospeedsecSlider
        function roundSlider(app, event)
            changingValue = event.Value;
            app.AutospeedsecSlider.Value = round(changingValue);
            app.autoPeriod = app.AutospeedsecSlider.Value;
            
            if get(app.t,'Running') == 'on' 
                stop(app.t)
                app.t.Period = app.autoPeriod;
                start(app.t)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConvolutionKernelMaskOperationLabel = uilabel(app.UIFigure);
            app.ConvolutionKernelMaskOperationLabel.Text = 'Convolution Kernel Mask Operation';
            app.PanelKernel = uipanel(app.UIFigure);
            app.PanelKernel.Title = 'Kernel';
            app.GridLayoutKernel = uigridlayout(app.PanelKernel);
            app.kernel_1_1 = uieditfield(app.GridLayoutKernel, 'numeric');
            app.kernel_1_2 = uieditfield(app.GridLayoutKernel, 'numeric');
            app.kernel_1_2.Value = -1;
            app.kernel_1_3 = uieditfield(app.GridLayoutKernel, 'numeric');
            app.kernel_2_1 = uieditfield(app.GridLayoutKernel, 'numeric');
            app.kernel_2_1.Value = -1;
            app.kernel_2_2 = uieditfield(app.GridLayoutKernel, 'numeric');
            app.kernel_2_2.Value = 5;
            app.kernel_2_3 = uieditfield(app.GridLayoutKernel, 'numeric');
            app.kernel_2_3.Value = -1;
            app.kernel_3_1 = uieditfield(app.GridLayoutKernel, 'numeric');
            app.kernel_3_2 = uieditfield(app.GridLayoutKernel, 'numeric');
            app.kernel_3_2.Value = -1;
            app.kernel_3_3 = uieditfield(app.GridLayoutKernel, 'numeric');
            app.KerneltimesImagePanel = uipanel(app.UIFigure);
            app.KerneltimesImagePanel.Title = 'Kernel times Image ';
            app.GridLayoutKernelTimesImage = uigridlayout(app.KerneltimesImagePanel);
            app.times_1_1 = uieditfield(app.GridLayoutKernelTimesImage, 'numeric');
            app.times_1_1.Editable = 'off';
            app.times_1_2 = uieditfield(app.GridLayoutKernelTimesImage, 'numeric');
            app.times_1_2.Editable = 'off';
            app.times_1_3 = uieditfield(app.GridLayoutKernelTimesImage, 'numeric');
            app.times_1_3.Editable = 'off';
            app.times_2_1 = uieditfield(app.GridLayoutKernelTimesImage, 'numeric');
            app.times_2_1.Editable = 'off';
            app.times_2_2 = uieditfield(app.GridLayoutKernelTimesImage, 'numeric');
            app.times_2_2.Editable = 'off';
            app.times_2_3 = uieditfield(app.GridLayoutKernelTimesImage, 'numeric');
            app.times_2_3.Editable = 'off';
            app.times_3_1 = uieditfield(app.GridLayoutKernelTimesImage, 'numeric');
            app.times_3_1.Editable = 'off';
            app.times_3_2 = uieditfield(app.GridLayoutKernelTimesImage, 'numeric');
            app.times_3_2.Editable = 'off';
            app.times_3_3 = uieditfield(app.GridLayoutKernelTimesImage, 'numeric');
            app.times_3_3.Editable = 'off';
            app.ChooseaKernelButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseaKernelButtonGroup.Title = 'Choose a Kernel';
            app.AutoCheckBox = uicheckbox(app.ChooseaKernelButtonGroup);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @startAuto, true);
            app.AutoCheckBox.Text = 'Auto';
            app.KernelDropDownLabel = uilabel(app.ChooseaKernelButtonGroup);
            app.KernelDropDownLabel.Text = 'Kernel';
            app.KernelDropDown = uidropdown(app.ChooseaKernelButtonGroup);
            app.KernelDropDown.Items = {'Laplacian Kernel', 'Sharpening Kernel', 'Vertical Derivative', 'Smoothing Kernel'};
            app.KernelDropDown.ValueChangedFcn = createCallbackFcn(app, @GetKernelValues, true);
            app.KernelDropDown.Value = 'Laplacian Kernel';
            app.ManualButton = uibutton(app.ChooseaKernelButtonGroup, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @NextStep, true);
            app.ManualButton.Text = 'Manual';
            app.ResetButton = uibutton(app.ChooseaKernelButtonGroup, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @reset, true);
            app.ResetButton.Text = 'Reset';
            app.ImagePanel = uipanel(app.UIFigure);
            app.ImagePanel.Title = 'Image ';
            app.GridLayoutImage = uigridlayout(app.ImagePanel);
            app.image_1_2 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_1_2.Value = -1;
            app.image_1_1 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_1_1.Value = -2;
            app.image_1_3 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_1_3.Value = -3;
            app.image_2_1 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_2_1.Value = -4;
            app.image_2_2 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_2_2.Value = -5;
            app.image_2_3 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_2_3.Value = -6;
            app.image_3_1 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_3_1.Value = -7;
            app.image_3_2 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_3_2.Value = -8;
            app.image_3_3 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_3_3.Value = -9;
            app.image_1_4 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_1_4.Value = -10;
            app.image_1_5 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_1_5.Value = -11;
            app.image_2_4 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_2_4.Value = -12;
            app.image_2_5 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_2_5.Value = -13;
            app.image_3_4 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_3_4.Value = -14;
            app.image_3_5 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_3_5.Value = -15;
            app.image_4_1 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_4_1.Value = -16;
            app.image_4_2 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_4_2.Value = -17;
            app.image_4_3 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_4_3.Value = -18;
            app.image_4_4 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_4_4.Value = -19;
            app.image_4_5 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_4_5.Value = -20;
            app.image_5_1 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_5_1.Value = -21;
            app.image_5_2 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_5_2.Value = -22;
            app.image_5_3 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_5_3.Value = -23;
            app.image_5_4 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_5_4.Value = -24;
            app.image_5_5 = uieditfield(app.GridLayoutImage, 'numeric');
            app.image_5_5.Value = -25;
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.GridLayoutImage_2 = uigridlayout(app.OutputPanel);
            app.output_1_1 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_1_3 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_2_1 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_2_2 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_2_3 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_3_1 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_3_2 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_3_3 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_1_4 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_1_5 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_2_4 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_2_5 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_3_4 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_3_5 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_4_1 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_4_2 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_4_3 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_4_4 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_4_5 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_5_1 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_5_2 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_5_3 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_5_4 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_5_5 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.output_1_2 = uieditfield(app.GridLayoutImage_2, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.InputImagePanel = uipanel(app.Panel);
            app.InputImagePanel.Title = 'Input Image';
            app.InputImage = uiaxes(app.InputImagePanel);
            app.InputImage.XTick = [1 2 3 4 5];
            app.InputImage.XTickLabel = '';
            app.InputImage.YTick = [1 2 3 4 5];
            app.InputImage.YTickLabel = '';
            app.OutputImagePanel = uipanel(app.Panel);
            app.OutputImagePanel.Title = 'Output Image';
            app.OutputImage = uiaxes(app.OutputImagePanel);
            app.OutputImage.XTick = [1 2 3 4 5];
            app.OutputImage.XTickLabel = '';
            app.OutputImage.YTick = [1 2 3 4 5];
            app.OutputImage.YTickLabel = '';
            app.CalculusPanel = uipanel(app.UIFigure);
            app.CalculusPanel.Title = 'Calculus';
            app.GridLayoutCalculus = uigridlayout(app.CalculusPanel);
            app.Label = uilabel(app.GridLayoutCalculus);
            app.Label.Text = '[1,1]';
            app.EditField = uieditfield(app.GridLayoutCalculus, 'text');
            app.EditField.Value = '1+3';
            app.Label_2 = uilabel(app.GridLayoutCalculus);
            app.Label_2.Text = '[1,2]';
            app.EditField_2 = uieditfield(app.GridLayoutCalculus, 'text');
            app.EditField_2.Value = '1+3';
            app.Label_3 = uilabel(app.GridLayoutCalculus);
            app.Label_3.Text = '[1,3]';
            app.EditField_3 = uieditfield(app.GridLayoutCalculus, 'text');
            app.EditField_3.Value = '1+3';
            app.Label_4 = uilabel(app.GridLayoutCalculus);
            app.Label_4.Text = '[2,1]';
            app.EditField_4 = uieditfield(app.GridLayoutCalculus, 'text');
            app.EditField_4.Value = '1+3';
            app.Label_5 = uilabel(app.GridLayoutCalculus);
            app.Label_5.Text = '[2,2]';
            app.EditField_5 = uieditfield(app.GridLayoutCalculus, 'text');
            app.EditField_5.Value = '1+3';
            app.Label_6 = uilabel(app.GridLayoutCalculus);
            app.Label_6.Text = '[2,3]';
            app.EditField_6 = uieditfield(app.GridLayoutCalculus, 'text');
            app.EditField_6.Value = '1+3';
            app.Label_7 = uilabel(app.GridLayoutCalculus);
            app.Label_7.Text = '[3,1]';
            app.EditField_7 = uieditfield(app.GridLayoutCalculus, 'text');
            app.EditField_7.Value = '1+3';
            app.Label_8 = uilabel(app.GridLayoutCalculus);
            app.Label_8.Text = '[3,2]';
            app.EditField_8 = uieditfield(app.GridLayoutCalculus, 'text');
            app.EditField_8.Value = '1+3';
            app.Label_9 = uilabel(app.GridLayoutCalculus);
            app.Label_9.Text = '[3,3]';
            app.EditField_9 = uieditfield(app.GridLayoutCalculus, 'text');
            app.EditField_9.Value = '1+3';
            app.TotalscaledEditFieldLabel = uilabel(app.GridLayoutCalculus);
            app.TotalscaledEditFieldLabel.Text = 'Total(scaled) = ';
            app.TotalscaledEditField = uieditfield(app.GridLayoutCalculus, 'text');
            app.TotalscaledEditField.Value = '1+3';
            app.TotalEditFieldLabel = uilabel(app.GridLayoutCalculus);
            app.TotalEditFieldLabel.Text = 'Total = ';
            app.TotalEditField = uieditfield(app.GridLayoutCalculus, 'text');
            app.TotalEditField.Value = '1+3';
            app.AutospeedsecSlider = uislider(app.UIFigure);
            app.AutospeedsecSlider.Limits = [1 8];
            app.AutospeedsecSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.AutospeedsecSlider.ValueChangedFcn = createCallbackFcn(app, @roundSlider, true);
            app.AutospeedsecSlider.MinorTicks = [];
            app.AutospeedsecSlider.Value = 4;
            app.AutoSpeedLabel = uilabel(app.UIFigure);
            app.AutoSpeedLabel.Text = 'Auto Speed';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL8_KernelSpatialConvolutionApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end